classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panelsterowania              matlab.ui.container.Panel
        RozwiazButton                matlab.ui.control.Button
        BenchmarkButton              matlab.ui.control.Button
        PrzerwijButton               matlab.ui.control.Button
        RenderujanimacjButton        matlab.ui.control.Button
        AlgorytmDropDown             matlab.ui.control.DropDown
        AlgorytmDropDownLabel        matlab.ui.control.Label
        GeneracjaPanel               matlab.ui.container.Panel
        LiczbaobiektwEditField       matlab.ui.control.NumericEditField
        LiczbaobiektwEditFieldLabel  matlab.ui.control.Label
        Parametr2EditField           matlab.ui.control.NumericEditField
        Parametr2EditFieldLabel      matlab.ui.control.Label
        Parametr1EditField           matlab.ui.control.NumericEditField
        Parametr1Label               matlab.ui.control.Label
        NumerkryteriumDropDown       matlab.ui.control.DropDown
        NumerkryteriumDropDownLabel  matlab.ui.control.Label
        RozkadDropDown               matlab.ui.control.DropDown
        RozkadDropDownLabel          matlab.ui.control.Label
        GenerujButton                matlab.ui.control.Button
        EdytorwartociPanel           matlab.ui.container.Panel
        UsunButton_2                 matlab.ui.control.Button
        DodajButton_2                matlab.ui.control.Button
        UITable2                     matlab.ui.control.Table
        EdytorkryteriwPanel          matlab.ui.container.Panel
        UsunButton                   matlab.ui.control.Button
        DodajButton                  matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
    end


    methods (Access = public)
        
        function updateUITable2(app)
             % Get the number of criteria from UITable
                dataUITable = app.UITable.Data;
                if isempty(dataUITable)
                    numColumns = 0; % No columns if UITable is empty
                else
                    numColumns = size(dataUITable, 1) + 1; % Number of rows in UITable + 1 (for "Lp")
                end
                
                % Debugging
                fprintf('Updating UITable2: Number of Columns = %d\n', numColumns);
            
                % Update UITable2 ColumnName
                newColumnNames = cell(1, numColumns);
                for i = 1:numColumns
                    if i == 1
                        newColumnNames{i} = "Lp";
                    else
                        newColumnNames{i} = sprintf('Kryterium %d', i - 1);
                    end
                end
                app.UITable2.ColumnName = newColumnNames;
            
                % Retrieve current data from UITable2
                if isempty(app.UITable2.Data)
                    currentData = {}; % Initialize as empty if no data exists
                else
                    currentData = app.UITable2.Data; % Existing data
                end
            
                % Debugging
                fprintf('Current UITable2 Data Size: [%d, %d]\n', size(currentData, 1), size(currentData, 2));
            
                % If no columns are present, clear UITable2 data
                if numColumns == 0
                    app.UITable2.Data = []; % Clear data
                else
                    % Number of rows based on LiczbaobiektwEditField
                    numRows = app.LiczbaobiektwEditField.Value; 
                    newData = cell(numRows, numColumns);
            
                    % Fill the first column with row indices
                    for i = 1:numRows
                        newData{i, 1} = i;
                    end
            
                    % Debugging
                    fprintf('New Data Size (UITable2): [%d, %d]\n', size(newData, 1), size(newData, 2));
            
                    % Update UITable2 with new data
                    app.UITable2.Data = newData;
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DodajButton
        function DodajButtonPushed(app, event)
            % Pobierz istniejące dane z tabeli
            data = app.UITable.Data;
            
            % Ustal numer nowego wiersza (Liczba Porządkowa)
            if isempty(data)
                app.UITable.ColumnFormat = {[], [], {'Min', 'Max'}};
                newIndex = 1;
            else
                newIndex = size(data, 1) + 1;
            end
            
             % Automatycznie generuj nazwę w kolumnie "Nazwa"
            newName = sprintf('Kryterium %d', newIndex);
            
            % Dodaj nowy wiersz z automatycznie wypełnionymi wartościami
            newRow = {newIndex, newName, 'Min'}; % Domyślnie "Min" w kolumnie "Kierunek"
            data = [data; newRow];
            
            % Zaktualizuj dane tabeli
            app.UITable.Data = data;
            app.updateUITable2;
        end

        % Callback function: EdytorkryteriwPanel, UsunButton
        function UsunButtonPushed(app, event)
             % Pobierz istniejące dane z tabeli
            data = app.UITable.Data;
            
            % Usuń ostatni wiersz, jeśli dane nie są puste
            if ~isempty(data)
                data(end, :) = [];
            end
            
            % Zaktualizuj dane tabeli
            app.UITable.Data = data;
            app.updateUITable2;
        end

        % Button pushed function: DodajButton_2
        function DodajButton_2Pushed(app, event)
             % Get data from UITable2
            dataUITable2 = app.UITable2.Data; 
            
            % Determine the number of columns based on UITable2's ColumnName
            numColumns = length(app.UITable2.ColumnName);  % Use ColumnName to determine column count
            fprintf('Number of Columns in UITable2: %d\n', numColumns);
            
            % Handle the case where the table starts empty
            if isempty(dataUITable2)
                % Initialize an empty table with the correct number of columns
                dataUITable2 = cell(0, numColumns);
            end
            
            % Update the row index (LiczbaobiektwEditField.Value)
            if isempty(dataUITable2)
                app.LiczbaobiektwEditField.Value = 1;  % Set the first row index
            else
                app.LiczbaobiektwEditField.Value = size(dataUITable2, 1) + 1;  % Increment the row index
            end
            
            % Create a new row with the correct number of columns
            newRow = cell(1, numColumns);  % Ensure newRow has the correct number of columns
            newRow{1} = app.LiczbaobiektwEditField.Value;  % Set the row index (first element)
            
            % Debugging
            if isempty(dataUITable2)
                fprintf('UITable2 Before Adding Row: Empty\n');
            else
                fprintf('UITable2 Before Adding Row: %s\n', mat2str(size(dataUITable2)));
            end
        
            % Add the new row to UITable2
            dataUITable2 = [dataUITable2; newRow];  % Concatenate with consistent column sizes
            app.UITable2.Data = dataUITable2;
            
            % Debugging
            fprintf('UITable2 After Adding Row: %s\n', mat2str(size(app.UITable2.Data)));
        end

        % Button pushed function: UsunButton_2
        function UsunButton_2Pushed(app, event)
            % Pobierz dane z UITable2
            dataUITable2 = app.UITable2.Data;
            
            % Usuń ostatni wiersz, jeśli dane nie są puste
            if ~isempty(dataUITable2)
                dataUITable2(end, :) = [];
                
                % Ponowne przypisanie liczby porządkowej (opcjonalne, gdyby wiersze miały być przesuwane)
                for i = 1:size(dataUITable2, 1)
                    dataUITable2{i, 1} = i; % Aktualizacja liczb porządkowych
                end
                app.LiczbaobiektwEditField.Value = app.LiczbaobiektwEditField.Value -1;
            end
            
            % Zaktualizuj tabelę
            app.UITable2.Data = dataUITable2;        
        end

        % Button pushed function: GenerujButton
        function GenerujButtonPushed(app, event)
            
        end

        % Button pushed function: RozwiazButton
        function RozwiazButtonPushed(app, event)
            
        end

        % Button pushed function: BenchmarkButton
        function BenchmarkButtonPushed(app, event)
            
        end

        % Button pushed function: PrzerwijButton
        function PrzerwijButtonPushed(app, event)
            
        end

        % Button pushed function: RenderujanimacjButton
        function RenderujanimacjButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EdytorkryteriwPanel = uipanel(app.UIFigure);
            app.EdytorkryteriwPanel.Title = 'Edytor kryteriów';
            app.EdytorkryteriwPanel.ButtonDownFcn = createCallbackFcn(app, @UsunButtonPushed, true);
            app.UITable = uitable(app.EdytorkryteriwPanel);
            app.UITable.ColumnName = {'Lp'; 'Nazwa'; 'Kierunek'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [false true true];
            app.UITable.Multiselect = 'off';
            app.DodajButton = uibutton(app.EdytorkryteriwPanel, 'push');
            app.DodajButton.ButtonPushedFcn = createCallbackFcn(app, @DodajButtonPushed, true);
            app.DodajButton.Text = 'Dodaj';
            app.UsunButton = uibutton(app.EdytorkryteriwPanel, 'push');
            app.UsunButton.ButtonPushedFcn = createCallbackFcn(app, @UsunButtonPushed, true);
            app.UsunButton.Text = 'Usuń';
            app.EdytorwartociPanel = uipanel(app.UIFigure);
            app.EdytorwartociPanel.Title = 'Edytor wartości';
            app.UITable2 = uitable(app.EdytorwartociPanel);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.DodajButton_2 = uibutton(app.EdytorwartociPanel, 'push');
            app.DodajButton_2.ButtonPushedFcn = createCallbackFcn(app, @DodajButton_2Pushed, true);
            app.DodajButton_2.Text = 'Dodaj';
            app.UsunButton_2 = uibutton(app.EdytorwartociPanel, 'push');
            app.UsunButton_2.ButtonPushedFcn = createCallbackFcn(app, @UsunButton_2Pushed, true);
            app.UsunButton_2.Text = 'Usuń';
            app.GeneracjaPanel = uipanel(app.UIFigure);
            app.GeneracjaPanel.Title = 'Generacja';
            app.GenerujButton = uibutton(app.GeneracjaPanel, 'push');
            app.GenerujButton.ButtonPushedFcn = createCallbackFcn(app, @GenerujButtonPushed, true);
            app.GenerujButton.Text = 'Generuj';
            app.RozkadDropDownLabel = uilabel(app.GeneracjaPanel);
            app.RozkadDropDownLabel.Text = 'Rozkład';
            app.RozkadDropDown = uidropdown(app.GeneracjaPanel);
            app.RozkadDropDown.Items = {'jednostajny', 'gauss', 'eksponencjalny'};
            app.RozkadDropDown.Value = 'jednostajny';
            app.NumerkryteriumDropDownLabel = uilabel(app.GeneracjaPanel);
            app.NumerkryteriumDropDownLabel.Text = 'Numer kryterium';
            app.NumerkryteriumDropDown = uidropdown(app.GeneracjaPanel);
            app.NumerkryteriumDropDown.Items = {'1', '2', '3', '4'};
            app.NumerkryteriumDropDown.Value = '1';
            app.Parametr1Label = uilabel(app.GeneracjaPanel);
            app.Parametr1Label.Text = 'Parametr1';
            app.Parametr1EditField = uieditfield(app.GeneracjaPanel, 'numeric');
            app.Parametr2EditFieldLabel = uilabel(app.GeneracjaPanel);
            app.Parametr2EditFieldLabel.Text = 'Parametr2';
            app.Parametr2EditField = uieditfield(app.GeneracjaPanel, 'numeric');
            app.LiczbaobiektwEditFieldLabel = uilabel(app.GeneracjaPanel);
            app.LiczbaobiektwEditFieldLabel.Text = 'Liczba obiektów';
            app.LiczbaobiektwEditField = uieditfield(app.GeneracjaPanel, 'numeric');
            app.Panelsterowania = uipanel(app.UIFigure);
            app.Panelsterowania.Title = 'Panel sterowania';
            app.AlgorytmDropDownLabel = uilabel(app.Panelsterowania);
            app.AlgorytmDropDownLabel.Text = 'Algorytm';
            app.AlgorytmDropDown = uidropdown(app.Panelsterowania);
            app.AlgorytmDropDown.Items = {'Naiwny', 'Naiwny z sortowaniem'};
            app.AlgorytmDropDown.Value = 'Naiwny';
            app.RenderujanimacjButton = uibutton(app.Panelsterowania, 'push');
            app.RenderujanimacjButton.ButtonPushedFcn = createCallbackFcn(app, @RenderujanimacjButtonPushed, true);
            app.RenderujanimacjButton.Text = 'Renderuj animację';
            app.PrzerwijButton = uibutton(app.Panelsterowania, 'push');
            app.PrzerwijButton.ButtonPushedFcn = createCallbackFcn(app, @PrzerwijButtonPushed, true);
            app.PrzerwijButton.Text = 'Przerwij';
            app.BenchmarkButton = uibutton(app.Panelsterowania, 'push');
            app.BenchmarkButton.ButtonPushedFcn = createCallbackFcn(app, @BenchmarkButtonPushed, true);
            app.BenchmarkButton.Text = 'Benchmark';
            app.RozwiazButton = uibutton(app.Panelsterowania, 'push');
            app.RozwiazButton.ButtonPushedFcn = createCallbackFcn(app, @RozwiazButtonPushed, true);
            app.RozwiazButton.Text = 'Rozwiąż';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end